classdef PendantSimulator_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ExitButton              matlab.ui.control.Button
        EmergencyStopButton     matlab.ui.control.Button
        RZradEditField          matlab.ui.control.NumericEditField
        RZradEditFieldLabel     matlab.ui.control.Label
        RYradEditField          matlab.ui.control.NumericEditField
        RYradEditFieldLabel     matlab.ui.control.Label
        RXradEditField          matlab.ui.control.NumericEditField
        RXradEditFieldLabel     matlab.ui.control.Label
        ZmmEditField            matlab.ui.control.NumericEditField
        ZmmEditFieldLabel       matlab.ui.control.Label
        YmmEditField            matlab.ui.control.NumericEditField
        YmmEditFieldLabel       matlab.ui.control.Label
        XmmEditField            matlab.ui.control.NumericEditField
        XmmEditFieldLabel       matlab.ui.control.Label
        TCPLabel                matlab.ui.control.Label
        Button_26               matlab.ui.control.Button
        Button_25               matlab.ui.control.Button
        Button_24               matlab.ui.control.Button
        Button_23               matlab.ui.control.Button
        Button_22               matlab.ui.control.Button
        Button_21               matlab.ui.control.Button
        Button_20               matlab.ui.control.Button
        Button_19               matlab.ui.control.Button
        Button_18               matlab.ui.control.Button
        Button_17               matlab.ui.control.Button
        Button_16               matlab.ui.control.Button
        Button_15               matlab.ui.control.Button
        Wrist3EditField         matlab.ui.control.NumericEditField
        Wrist3EditFieldLabel    matlab.ui.control.Label
        Wrist2EditField         matlab.ui.control.NumericEditField
        Wrist2EditFieldLabel    matlab.ui.control.Label
        Wrist1EditField         matlab.ui.control.NumericEditField
        Wrist1EditFieldLabel    matlab.ui.control.Label
        ElbowEditField          matlab.ui.control.NumericEditField
        ElbowEditFieldLabel     matlab.ui.control.Label
        ShoulderEditField       matlab.ui.control.NumericEditField
        ShoulderEditFieldLabel  matlab.ui.control.Label
        BaseEditField           matlab.ui.control.NumericEditField
        BaseEditFieldLabel      matlab.ui.control.Label
        MoveJointsLabel         matlab.ui.control.Label
        TCPOrientationLabel     matlab.ui.control.Label
        TCPPositionLabel        matlab.ui.control.Label
        BackwardButton_2        matlab.ui.control.Button
        RightButton_2           matlab.ui.control.Button
        LeftButton_2            matlab.ui.control.Button
        CWButton                matlab.ui.control.Button
        ForwardButton_2         matlab.ui.control.Button
        ACWButton               matlab.ui.control.Button
        BackwardButton          matlab.ui.control.Button
        RightButton             matlab.ui.control.Button
        LeftButton              matlab.ui.control.Button
        DownButton              matlab.ui.control.Button
        ForwardButton           matlab.ui.control.Button
        UpButton                matlab.ui.control.Button
        PendantSimulatorLabel   matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BaseEditField
        function BaseEditFieldValueChanged(app, event)
            Base = app.BaseEditField.Value;
            app.BaseEditField.Value = 0;
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            app.BaseEditField.Value = app.BaseEditField.Value + 1;
        end

        % Button pushed function: Button_21
        function Button_21Pushed(app, event)
            app.BaseEditField.Value = app.BaseEditField.Value - 1;
        end

        % Value changed function: ShoulderEditField
        function ShoulderEditFieldValueChanged(app, event)
            Shoulder = app.ShoulderEditField.Value;
            app.ShoulderEditField.Value = 0;
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            app.ShoulderEditField.Value = app.ShoulderEditField.Value + 1;
        end

        % Button pushed function: Button_22
        function Button_22Pushed(app, event)
            app.ShoulderEditField.Value = app.ShoulderEditField.Value - 1;
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            app.ElbowEditField.Value = app.ElbowEditField.Value + 1;
        end

        % Value changed function: ElbowEditField
        function ElbowEditFieldValueChanged(app, event)
            Elbow = app.ElbowEditField.Value;
            app.ElbowEditField.Value = 0;
        end

        % Button pushed function: Button_23
        function Button_23Pushed(app, event)
            app.ElbowEditField.Value = app.ElbowEditField.Value - 1;
        end

        % Value changed function: Wrist1EditField
        function Wrist1EditFieldValueChanged(app, event)
            Wrist1 = app.Wrist1EditField.Value;
            app.Wrist1EditField.Value = 0;
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
            app.Wrist1EditField.Value = app.Wrist1EditField.Value + 1;
        end

        % Button pushed function: Button_24
        function Button_24Pushed(app, event)
            app.Wrist1EditField.Value = app.Wrist1EditField.Value - 1;
        end

        % Value changed function: Wrist2EditField
        function Wrist2EditFieldValueChanged(app, event)
            Wrist2 = app.Wrist2EditField.Value;
            app.Wrist2EditField.Value = 0;
        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            app.Wrist2EditField.Value = app.Wrist2EditField.Value + 1;
        end

        % Button pushed function: Button_25
        function Button_25Pushed(app, event)
            app.Wrist2EditField.Value = app.Wrist2EditField.Value - 1;
        end

        % Value changed function: Wrist3EditField
        function Wrist3EditFieldValueChanged(app, event)
            Wrist3 = app.Wrist3EditField.Value;
            app.Wrist3EditField.Value = 0;
        end

        % Button pushed function: Button_20
        function Button_20Pushed(app, event)
            app.Wrist3EditField.Value = app.Wrist3EditField.Value + 1;
        end

        % Button pushed function: Button_26
        function Button_26Pushed(app, event)
            app.Wrist3EditField.Value = app.Wrist3EditField.Value - 1;
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq();
            MainMenu_GUI
        end

        % Value changed function: XmmEditField
        function XmmEditFieldValueChanged(app, event)
            value = app.XmmEditField.Value;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            display('pressed down on graph')
            x = 1:10;
            y = 1:10;
            z = 1:10;
plot3(app.UIAxes,x,y,z);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.PendantSimulatorLabel = uilabel(app.UIFigure);
            app.PendantSimulatorLabel.Text = 'Pendant Simulator';
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.Text = 'Up';
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.Text = 'Forward';
            app.DownButton = uibutton(app.UIFigure, 'push');
            app.DownButton.Text = 'Down';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.Text = 'Right';
            app.BackwardButton = uibutton(app.UIFigure, 'push');
            app.BackwardButton.Text = 'Backward';
            app.ACWButton = uibutton(app.UIFigure, 'push');
            app.ACWButton.Text = 'ACW';
            app.ForwardButton_2 = uibutton(app.UIFigure, 'push');
            app.ForwardButton_2.Text = 'Forward';
            app.CWButton = uibutton(app.UIFigure, 'push');
            app.CWButton.Text = 'CW';
            app.LeftButton_2 = uibutton(app.UIFigure, 'push');
            app.LeftButton_2.Text = 'Left';
            app.RightButton_2 = uibutton(app.UIFigure, 'push');
            app.RightButton_2.Text = 'Right';
            app.BackwardButton_2 = uibutton(app.UIFigure, 'push');
            app.BackwardButton_2.Text = 'Backward';
            app.TCPPositionLabel = uilabel(app.UIFigure);
            app.TCPPositionLabel.Text = 'TCP Position';
            app.TCPOrientationLabel = uilabel(app.UIFigure);
            app.TCPOrientationLabel.Text = 'TCP Orientation';
            app.MoveJointsLabel = uilabel(app.UIFigure);
            app.MoveJointsLabel.Text = 'Move Joints';
            app.BaseEditFieldLabel = uilabel(app.UIFigure);
            app.BaseEditFieldLabel.Text = 'Base';
            app.BaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaseEditField.ValueChangedFcn = createCallbackFcn(app, @BaseEditFieldValueChanged, true);
            app.ShoulderEditFieldLabel = uilabel(app.UIFigure);
            app.ShoulderEditFieldLabel.Text = 'Shoulder';
            app.ShoulderEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShoulderEditField.ValueChangedFcn = createCallbackFcn(app, @ShoulderEditFieldValueChanged, true);
            app.ElbowEditFieldLabel = uilabel(app.UIFigure);
            app.ElbowEditFieldLabel.Text = 'Elbow';
            app.ElbowEditField = uieditfield(app.UIFigure, 'numeric');
            app.ElbowEditField.ValueChangedFcn = createCallbackFcn(app, @ElbowEditFieldValueChanged, true);
            app.Wrist1EditFieldLabel = uilabel(app.UIFigure);
            app.Wrist1EditFieldLabel.Text = 'Wrist 1';
            app.Wrist1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Wrist1EditField.ValueChangedFcn = createCallbackFcn(app, @Wrist1EditFieldValueChanged, true);
            app.Wrist2EditFieldLabel = uilabel(app.UIFigure);
            app.Wrist2EditFieldLabel.Text = 'Wrist 2';
            app.Wrist2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Wrist2EditField.ValueChangedFcn = createCallbackFcn(app, @Wrist2EditFieldValueChanged, true);
            app.Wrist3EditFieldLabel = uilabel(app.UIFigure);
            app.Wrist3EditFieldLabel.Text = 'Wrist 3';
            app.Wrist3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Wrist3EditField.ValueChangedFcn = createCallbackFcn(app, @Wrist3EditFieldValueChanged, true);
            app.Button_15 = uibutton(app.UIFigure, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '<--';
            app.Button_16 = uibutton(app.UIFigure, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '<--';
            app.Button_17 = uibutton(app.UIFigure, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Text = '<--';
            app.Button_18 = uibutton(app.UIFigure, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = '<--';
            app.Button_19 = uibutton(app.UIFigure, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Text = '<--';
            app.Button_20 = uibutton(app.UIFigure, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @Button_20Pushed, true);
            app.Button_20.Text = '<--';
            app.Button_21 = uibutton(app.UIFigure, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @Button_21Pushed, true);
            app.Button_21.Text = '-->';
            app.Button_22 = uibutton(app.UIFigure, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @Button_22Pushed, true);
            app.Button_22.Text = '-->';
            app.Button_23 = uibutton(app.UIFigure, 'push');
            app.Button_23.ButtonPushedFcn = createCallbackFcn(app, @Button_23Pushed, true);
            app.Button_23.Text = '-->';
            app.Button_24 = uibutton(app.UIFigure, 'push');
            app.Button_24.ButtonPushedFcn = createCallbackFcn(app, @Button_24Pushed, true);
            app.Button_24.Text = '-->';
            app.Button_25 = uibutton(app.UIFigure, 'push');
            app.Button_25.ButtonPushedFcn = createCallbackFcn(app, @Button_25Pushed, true);
            app.Button_25.Text = '-->';
            app.Button_26 = uibutton(app.UIFigure, 'push');
            app.Button_26.ButtonPushedFcn = createCallbackFcn(app, @Button_26Pushed, true);
            app.Button_26.Text = '-->';
            app.TCPLabel = uilabel(app.UIFigure);
            app.TCPLabel.Text = 'TCP';
            app.XmmEditFieldLabel = uilabel(app.UIFigure);
            app.XmmEditFieldLabel.Text = 'X (mm)';
            app.XmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.XmmEditField.Limits = [-360 360];
            app.XmmEditField.ValueChangedFcn = createCallbackFcn(app, @XmmEditFieldValueChanged, true);
            app.XmmEditField.Editable = 'off';
            app.YmmEditFieldLabel = uilabel(app.UIFigure);
            app.YmmEditFieldLabel.Text = 'Y (mm)';
            app.YmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.YmmEditField.Editable = 'off';
            app.ZmmEditFieldLabel = uilabel(app.UIFigure);
            app.ZmmEditFieldLabel.Text = 'Z (mm)';
            app.ZmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZmmEditField.Editable = 'off';
            app.RXradEditFieldLabel = uilabel(app.UIFigure);
            app.RXradEditFieldLabel.Text = 'RX (rad)';
            app.RXradEditField = uieditfield(app.UIFigure, 'numeric');
            app.RXradEditField.Editable = 'off';
            app.RYradEditFieldLabel = uilabel(app.UIFigure);
            app.RYradEditFieldLabel.Text = 'RY (rad)';
            app.RYradEditField = uieditfield(app.UIFigure, 'numeric');
            app.RYradEditField.Editable = 'off';
            app.RZradEditFieldLabel = uilabel(app.UIFigure);
            app.RZradEditFieldLabel.Text = 'RZ (rad)';
            app.RZradEditField = uieditfield(app.UIFigure, 'numeric');
            app.RZradEditField.Editable = 'off';
            app.EmergencyStopButton = uibutton(app.UIFigure, 'push');
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PendantSimulator_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end