classdef RobotWelder_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CoreButton                  matlab.ui.control.Button
        RoboWelderMainLabel         matlab.ui.control.Label
        EmergencyStopButton         matlab.ui.control.Button
        ExitButton                  matlab.ui.control.Button
        EnvironmentButton           matlab.ui.control.Button
        RobotButton                 matlab.ui.control.Button
        SensorButton_2              matlab.ui.control.Button
        ControllerButton            matlab.ui.control.Button
        InitialiseLabel             matlab.ui.control.Label
        RunButton                   matlab.ui.control.Button
        SimulatonToggleSliderLabel  matlab.ui.control.Label
        SimulatonToggleSlider       matlab.ui.control.Slider
    end

    
    properties (Access = public)
        Core % Description
        value
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq();
            MainMenu_GUI
        end

        % Value changed function: SimulatonToggleSlider
        function SimulatonToggleSliderValueChanged(app, event)
            
            app.value = app.SimulatonToggleSlider.Value;
            
            disp(num2str(app.value))
%             if value == 0
%                 simStatus = false;
%             else
%                 simStatus = true;
%             end                  
                       
        end

        % Button pushed function: CoreButton
        function CoreButtonPushed(app, event)
            app.Core = WelderCore(true);
            disp("Core Created!!!")
        end

        % Button pushed function: EnvironmentButton
        function EnvironmentButtonPushed(app, event)
            app.Core.initialiseEnvironment()
            disp("Environment has been initialised")
            hold on
        end

        % Button pushed function: RobotButton
        function RobotButtonPushed(app, event)
            app.Core.initialiseRobot();
            disp("Robot has been initialised")
            hold on
        end

        % Button pushed function: SensorButton_2
        function SensorButton_2Pushed(app, event)

            
%             if app.value == 0
%                 app.Core.initialiseSensor(false,true)
%                 disp("Sensor has been initialised for the real-world")
%             else
%                 app.Core.initialiseSensor(true,true)
%                 disp("Sensor has been initialised for the simulation")
%             end
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CoreButton = uibutton(app.UIFigure, 'push');
            app.CoreButton.ButtonPushedFcn = createCallbackFcn(app, @CoreButtonPushed, true);
            app.CoreButton.Text = 'Core';
            app.RoboWelderMainLabel = uilabel(app.UIFigure);
            app.RoboWelderMainLabel.Text = 'RoboWelder Main';
            app.EmergencyStopButton = uibutton(app.UIFigure, 'push');
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.EnvironmentButton = uibutton(app.UIFigure, 'push');
            app.EnvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @EnvironmentButtonPushed, true);
            app.EnvironmentButton.Text = 'Environment';
            app.RobotButton = uibutton(app.UIFigure, 'push');
            app.RobotButton.ButtonPushedFcn = createCallbackFcn(app, @RobotButtonPushed, true);
            app.RobotButton.Text = 'Robot';
            app.SensorButton_2 = uibutton(app.UIFigure, 'push');
            app.SensorButton_2.ButtonPushedFcn = createCallbackFcn(app, @SensorButton_2Pushed, true);
            app.SensorButton_2.Text = 'Sensor';
            app.ControllerButton = uibutton(app.UIFigure, 'push');
            app.ControllerButton.Text = 'Controller';
            app.InitialiseLabel = uilabel(app.UIFigure);
            app.InitialiseLabel.Text = 'Initialise';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.Text = 'Run';
            app.SimulatonToggleSliderLabel = uilabel(app.UIFigure);
            app.SimulatonToggleSliderLabel.Text = 'Simulaton Toggle';
            app.SimulatonToggleSlider = uislider(app.UIFigure);
            app.SimulatonToggleSlider.Limits = [0 1];
            app.SimulatonToggleSlider.MajorTicks = [0 1];
            app.SimulatonToggleSlider.MajorTickLabels = {'0.1'};
            app.SimulatonToggleSlider.ValueChangedFcn = createCallbackFcn(app, @SimulatonToggleSliderValueChanged, true);
            app.SimulatonToggleSlider.MinorTicks = [0 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotWelder_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end